classdef logPressure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DataDisplayPanel              matlab.ui.container.Panel
        TabGroup2                     matlab.ui.container.TabGroup
        GraphixTab_2                  matlab.ui.container.Tab
        Label                         matlab.ui.control.Label
        CH3Label                      matlab.ui.control.Label
        CH2Label                      matlab.ui.control.Label
        OFFLabel_3                    matlab.ui.control.Label
        OFFLabel_2                    matlab.ui.control.Label
        TC08degCTab                   matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        StatusPanel                   matlab.ui.container.Panel
        TC08Label                     matlab.ui.control.Label
        DisconnectedLamp_2            matlab.ui.control.Lamp
        DisconnectedLamp_2Label       matlab.ui.control.Label
        GraphixLabel                  matlab.ui.control.Label
        DisconnectedLamp              matlab.ui.control.Lamp
        DisconnectedLampLabel         matlab.ui.control.Label
        ExportDataPanel               matlab.ui.container.Panel
        SaveButton                    matlab.ui.control.Button
        FilenameEditField             matlab.ui.control.EditField
        FilenameEditFieldLabel        matlab.ui.control.Label
        MeasurementSetUpPanel         matlab.ui.container.Panel
        AutosavetoFCheckBox           matlab.ui.control.CheckBox
        SampleGeometryDropDown        matlab.ui.control.DropDown
        SampleGeometryDropDownLabel   matlab.ui.control.Label
        SampleNameEditField           matlab.ui.control.EditField
        SampleNameEditFieldLabel      matlab.ui.control.Label
        MeasurementTypeDropDown       matlab.ui.control.DropDown
        MeasurementTypeDropDownLabel  matlab.ui.control.Label
        DurationEditField             matlab.ui.control.NumericEditField
        DurationEditFieldLabel        matlab.ui.control.Label
        IntervalsSpinner              matlab.ui.control.Spinner
        IntervalsSpinnerLabel         matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        AbortButton                   matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        ConnectDevicesPanel           matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        GraphixTab                    matlab.ui.container.Tab
        PortDropDown                  matlab.ui.control.DropDown
        PortDropDownLabel             matlab.ui.control.Label
        ButtonGroup                   matlab.ui.container.ButtonGroup
        DisconnectButton              matlab.ui.control.ToggleButton
        ConnectButton                 matlab.ui.control.ToggleButton
        TC08Tab                       matlab.ui.container.Tab
        ButtonGroup_2                 matlab.ui.container.ButtonGroup
        DisconnectButton_2            matlab.ui.control.ToggleButton
        ConnectButton_2               matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        grx = 0; % Description
        isAbortLog = 0; % Description
        t_arr = 0; % Description
        p2_arr = 0;
        p3_arr = 0;
        unithandle = 0; % Description
        temp_arr = 0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % on start clear variables. This deletes any serialport which
            % have not been closed
            clear
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            % connect if connect button is pressed
            if app.ConnectButton.Value
                try
                    app.grx = serialport(app.PortDropDown.Value, 9600); % open serialport with name and baud rate
                    configureTerminator(app.grx, 04); % set terminator (EOT in graphix manual)
                    flush(app.grx);
                    % Lamp indicates connection status (green=connected)
                    app.DisconnectedLampLabel.Text = "Connected";
                    app.DisconnectedLamp.Color = [0 1 0];
                    % enable logging panel
                    children = get(app.MeasurementSetUpPanel,'Children');
                    set(children(isprop(children,'Enable')),'Enable','on');
                    app.AbortButton.Enable = "off";
                catch ME
                    % Indicate connection error
                    app.DisconnectedLampLabel.Text = "Connection Error";
                    app.DisconnectedLamp.Color = [1 0 0];
                    rethrow(ME)
                end
            end
            % Disconnect if disconnect button is pressed
            if app.DisconnectButton.Value
                app.grx = []; % disconnect by deleting serialport variable
                % indicate disconnection
                app.DisconnectedLampLabel.Text = "Disconnected";
                app.DisconnectedLamp.Color = [1 1 1];
                % Disable logging panel
                children = get(app.MeasurementSetUpPanel,'Children');
                set(children(isprop(children,'Enable')),'Enable','off');
            end
        end

        % Button pushed function: StartButton
        function StartLogging(app, event)
            
            % Disable everything but abort. Should not start logging again
            % until run is complete or aborted.
            log_children = get(app.MeasurementSetUpPanel,'Children');
            set(log_children(isprop(log_children,'Enable')),'Enable','off');
            app.AbortButton.Enable = "on";
            
            % disable export
            export_children = get(app.ExportDataPanel,'Children');
            set(export_children(isprop(export_children,'Enable')),'Enable','off');
            
            % format datetime for filename
            fn_date = string(datetime('now', "Format", 'yyyyMMdd_HHmmss'));
            % Add measurement info to filename
            if app.MeasurementTypeDropDown.Value == "Reference"
                app.FilenameEditField.Value = strcat("ref_", fn_date, ".txt");
            end
            % for measurements with samples add sample geometry + name
            if app.MeasurementTypeDropDown.Value == "Sample"
                % remove spaces from name
                fn_sample = strrep(app.SampleNameEditField.Value, " ", "");
                fn_geom = app.SampleGeometryDropDown.Value;
                app.FilenameEditField.Value = strcat(fn_sample, "_", fn_geom, "_", fn_date, ".txt");
            end
            
            
            % get duration and interval from input
            if app.DropDown.Value == "sec"
                duration = app.DurationEditField.Value;
            elseif app.DropDown.Value == "mins"
                duration = app.DurationEditField.Value * 60;
            elseif app.DropDown.Value == "hrs"
                duration = app.DurationEditField.Value * 60 * 60;
            end
            
            interval = app.IntervalsSpinner.Value;
            
            %% TC-08 Set up
            typek = 'K'; % Sensor type
            % pointer objects for tc-08 api commands. This a c thing that i
            % don't really understand :)
            pBufferChs = libpointer('singlePtr', zeros(1, 9, 'single'));
            overflow = libpointer('int16Ptr', zeros(1, 1, 'int16'));

            % Enable all 8 channels
            for n = 2:9
                status.setChannel = calllib('usbtc08', 'usb_tc08_set_channel', app.unithandle, ...
                    (n - 1), int8(typek));
            end

            % Set mains filter to 50Hz
            status.mainsFilter = calllib('usbtc08', 'usb_tc08_set_mains', app.unithandle, 0);
            
            %% Graphix set up
            % commands for pressures
            pcom2 = "2;29";
            pcom3 = "3;29";
            
            % send strings to get graphix pressures (see manual)
            sstr2 = makeSendString(pcom2);
            sstr3 = makeSendString(pcom3);
            
            n_samples = floor(duration/interval) + 1; % No. of samples
            
            % Preallocate arrays
            app.t_arr = NaT(n_samples, 1);
            app.p2_arr = zeros(n_samples, 1);
            app.p3_arr = zeros(n_samples, 1);
            app.temp_arr = zeros(n_samples, 9);
            
            % start sampling loop
            counter = 1;
            while counter <= n_samples
                
                t = datetime; % get time
                
                % get pressures from graphix
                resp2 = char(writeread(app.grx, sstr2));
                resp3 = char(writeread(app.grx, sstr3));
                
                % Get temps from tc-08
                [~, temps, ~] = calllib('usbtc08', 'usb_tc08_get_single', app.unithandle, pBufferChs, overflow, 0);
                

                % check if abort button has been pressed
                if app.isAbortLog
                    break
                end
                                          
                % convert from receive string to pressure
                % receive strings are in format *p* where * are ascii
                % chars
                p2 = resp2(2:end-1);
                p3 = resp3(2:end-1);
                
                
                % display pressure
                app.OFFLabel_2.Text = strcat(p2, ' mbar');
                app.OFFLabel_3.Text = strcat(p3, ' mbar');
                
                % update arrays
                app.t_arr(counter) = t;
                app.p2_arr(counter) = str2num(p2);
                app.p3_arr(counter) = str2num(p3);
                app.temp_arr(counter, :) = temps;
                app.UITable.Data = temps;
                counter = counter + 1; % increment counter
                
                % wait until time for next measurement
                run_time = seconds(datetime - t);
                pause(interval - run_time);
            end
            % Autosave to f: if requested
            if app.AutosavetoFCheckBox.Value == true
                autosave_path = "F:\temp\Catkins\OG_pressure_data\";
                var_names = {'Datetime', 'CH2', 'CH3', 'T1', 'T2', 'T3', 'T4', 'T5', 'T6', 'T7', 'T8', 'T9'};
                out_table = table(app.t_arr, app.p2_arr, app.p3_arr, app.temp_arr(:,1), app.temp_arr(:,2), app.temp_arr(:,3), app.temp_arr(:,4), ...
                    app.temp_arr(:,5), app.temp_arr(:,6), app.temp_arr(:,7), app.temp_arr(:,8), app.temp_arr(:,9), 'VariableNames', var_names);
                writetable(out_table, strcat(autosave_path, app.FilenameEditField.Value));
            end
            
            % Reset pressure display
            app.OFFLabel_2.Text = "OFF";
            app.OFFLabel_3.Text = "OFF";
            set(log_children(isprop(log_children,'Enable')),'Enable','on');
            app.AbortButton.Enable = "off";
            app.isAbortLog = 0;
            
            % enable export panel            
            set(export_children(isprop(export_children,'Enable')),'Enable','on');
            
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            app.isAbortLog = 1;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Manual save option
            var_names = {'Datetime', 'CH2', 'CH3', 'T1', 'T2', 'T3', 'T4', 'T5', 'T6', 'T7', 'T8', 'T9'};
            out_table = table(app.t_arr, app.p2_arr, app.p3_arr, app.temp_arr(:,1), app.temp_arr(:,2), app.temp_arr(:,3), app.temp_arr(:,4), ...
                app.temp_arr(:,5), app.temp_arr(:,6), app.temp_arr(:,7), app.temp_arr(:,8), app.temp_arr(:,9), 'VariableNames', var_names);
            % use file explorer to select path
            [fn, path] = uiputfile(app.FilenameEditField.Value);
            writetable(out_table, strcat(path,fn));
            
        end

        % Value changed function: MeasurementTypeDropDown
        function MeasurementTypeDropDownValueChanged(app, event)
            % for sample measurements display geometry and name fields
            value = app.MeasurementTypeDropDown.Value;
            if value == "Sample"
                app.SampleNameEditField.Visible = "on";
                app.SampleGeometryDropDownLabel.Visible = "on";
                app.SampleGeometryDropDown.Visible = "on";
                app.SampleNameEditFieldLabel.Visible = "on";                
            end
            if value == "Reference"
                app.SampleNameEditField.Visible = "off";
                app.SampleGeometryDropDownLabel.Visible = "off";
                app.SampleGeometryDropDown.Visible = "off";
                app.SampleNameEditFieldLabel.Visible = "off";      
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            % TC-08 connection
            if app.ConnectButton_2.Value
                % configuration script
                % configures device and loads command libraries
                USBTC08Config;
                % connect to device
                app.unithandle = calllib('usbtc08', 'usb_tc08_open_unit');
                % Veirfy connection status
                if app.unithandle > 0
                    disp('Connected to USB TC-08 device...');
                    fprintf('\n');
                    % Lamp indicates connection status (green=connected)
                    app.DisconnectedLamp_2Label.Text = "Connected";
                    app.DisconnectedLamp_2.Color = [0 1 0];
                elseif app.unithandle == 0
                    % Lamp indicates connection status (red=error)
                    app.DisconnectedLamp_2Label.Text = "Connection Error";
                    app.DisconnectedLamp_2.Color = [0 1 0];
                    warning('USBTC08Example:NoMoreUnitsFound', 'No more USB TC-08 units found');
                else
                     % Lamp indicates connection status (red=error)
                    app.DisconnectedLamp_2Label.Text = "Connection Error";
                    app.DisconnectedLamp_2.Color = [0 1 0];
                    % unload the command library to allow reconnection
                    % attempt
                    unloadlibrary('usbtc08');
                    error('USBTC08Example:UnitFailedToOpen', 'Unit failed to open.');
                    return;
                end
            end
            % disconnect and clean up
            if app.DisconnectButton_2.Value
                stop = calllib('usbtc08', 'usb_tc08_stop', app.unithandle);
                exit = calllib('usbtc08', 'usb_tc08_close_unit', app.unithandle);
                unloadlibrary('usbtc08');
                app.DisconnectedLamp_2Label.Text = "Disconnected";
                app.DisconnectedLamp_2.Color = [1 1 1];
                 
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % clean up if closed
            if app.ConnectButton_2.Value
                stop = calllib('usbtc08', 'usb_tc08_stop', app.unithandle);
                exit = calllib('usbtc08', 'usb_tc08_close_unit', app.unithandle);
                unloadlibrary('usbtc08');
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConnectDevicesPanel = uipanel(app.UIFigure);
            app.ConnectDevicesPanel.Title = 'Connect Devices';
            app.TabGroup = uitabgroup(app.ConnectDevicesPanel);
            app.GraphixTab = uitab(app.TabGroup);
            app.GraphixTab.Title = 'Graphix';
            app.ButtonGroup = uibuttongroup(app.GraphixTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ConnectButton = uitogglebutton(app.ButtonGroup);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uitogglebutton(app.ButtonGroup);
            app.DisconnectButton.Text = 'Disconnect';
            app.DisconnectButton.Value = true;
            app.PortDropDownLabel = uilabel(app.GraphixTab);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.GraphixTab);
            app.PortDropDown.Items = {'COM3', 'COM6', 'COM4', 'COM1', '', ''};
            app.PortDropDown.Value = 'COM3';
            app.TC08Tab = uitab(app.TabGroup);
            app.TC08Tab.Title = 'TC-08';
            app.ButtonGroup_2 = uibuttongroup(app.TC08Tab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ConnectButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.ConnectButton_2.Text = 'Connect';
            app.DisconnectButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.DisconnectButton_2.Text = 'Disconnect';
            app.DisconnectButton_2.Value = true;
            app.MeasurementSetUpPanel = uipanel(app.UIFigure);
            app.MeasurementSetUpPanel.Title = 'Measurement Set Up';
            app.StartButton = uibutton(app.MeasurementSetUpPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartLogging, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = {'Start'; ''};
            app.AbortButton = uibutton(app.MeasurementSetUpPanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Enable = 'off';
            app.AbortButton.Text = 'Abort';
            app.DropDown = uidropdown(app.MeasurementSetUpPanel);
            app.DropDown.Items = {'sec', 'mins', 'hrs', ''};
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'sec';
            app.IntervalsSpinnerLabel = uilabel(app.MeasurementSetUpPanel);
            app.IntervalsSpinnerLabel.Enable = 'off';
            app.IntervalsSpinnerLabel.Text = 'Interval (s)';
            app.IntervalsSpinner = uispinner(app.MeasurementSetUpPanel);
            app.IntervalsSpinner.Limits = [1 Inf];
            app.IntervalsSpinner.Enable = 'off';
            app.IntervalsSpinner.Value = 5;
            app.DurationEditFieldLabel = uilabel(app.MeasurementSetUpPanel);
            app.DurationEditFieldLabel.Enable = 'off';
            app.DurationEditFieldLabel.Text = {'Duration'; ''};
            app.DurationEditField = uieditfield(app.MeasurementSetUpPanel, 'numeric');
            app.DurationEditField.Limits = [1 Inf];
            app.DurationEditField.Enable = 'off';
            app.DurationEditField.Value = 20;
            app.MeasurementTypeDropDownLabel = uilabel(app.MeasurementSetUpPanel);
            app.MeasurementTypeDropDownLabel.Enable = 'off';
            app.MeasurementTypeDropDownLabel.Text = 'Measurement Type';
            app.MeasurementTypeDropDown = uidropdown(app.MeasurementSetUpPanel);
            app.MeasurementTypeDropDown.Items = {'Reference', 'Sample'};
            app.MeasurementTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MeasurementTypeDropDownValueChanged, true);
            app.MeasurementTypeDropDown.Enable = 'off';
            app.MeasurementTypeDropDown.Value = 'Reference';
            app.SampleNameEditFieldLabel = uilabel(app.MeasurementSetUpPanel);
            app.SampleNameEditFieldLabel.Visible = 'off';
            app.SampleNameEditFieldLabel.Text = 'Sample Name';
            app.SampleNameEditField = uieditfield(app.MeasurementSetUpPanel, 'text');
            app.SampleNameEditField.Visible = 'off';
            app.SampleGeometryDropDownLabel = uilabel(app.MeasurementSetUpPanel);
            app.SampleGeometryDropDownLabel.Visible = 'off';
            app.SampleGeometryDropDownLabel.Text = 'Sample Geometry';
            app.SampleGeometryDropDown = uidropdown(app.MeasurementSetUpPanel);
            app.SampleGeometryDropDown.Items = {'Cube', 'Lattice', 'Other'};
            app.SampleGeometryDropDown.Visible = 'off';
            app.SampleGeometryDropDown.Value = 'Cube';
            app.AutosavetoFCheckBox = uicheckbox(app.MeasurementSetUpPanel);
            app.AutosavetoFCheckBox.Enable = 'off';
            app.AutosavetoFCheckBox.Text = 'Autosave to F:';
            app.AutosavetoFCheckBox.Value = true;
            app.ExportDataPanel = uipanel(app.UIFigure);
            app.ExportDataPanel.Title = 'Export Data';
            app.FilenameEditFieldLabel = uilabel(app.ExportDataPanel);
            app.FilenameEditFieldLabel.Enable = 'off';
            app.FilenameEditFieldLabel.Text = {'Filename'; ''};
            app.FilenameEditField = uieditfield(app.ExportDataPanel, 'text');
            app.FilenameEditField.Enable = 'off';
            app.FilenameEditField.Value = 'test.txt';
            app.SaveButton = uibutton(app.ExportDataPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save..';
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.DisconnectedLampLabel = uilabel(app.StatusPanel);
            app.DisconnectedLampLabel.Text = 'Disconnected';
            app.DisconnectedLamp = uilamp(app.StatusPanel);
            app.GraphixLabel = uilabel(app.StatusPanel);
            app.GraphixLabel.Text = 'Graphix:';
            app.DisconnectedLamp_2Label = uilabel(app.StatusPanel);
            app.DisconnectedLamp_2Label.Text = 'Disconnected';
            app.DisconnectedLamp_2 = uilamp(app.StatusPanel);
            app.TC08Label = uilabel(app.StatusPanel);
            app.TC08Label.Text = 'TC-08:';
            app.DataDisplayPanel = uipanel(app.UIFigure);
            app.DataDisplayPanel.Title = 'Data Display';
            app.TabGroup2 = uitabgroup(app.DataDisplayPanel);
            app.GraphixTab_2 = uitab(app.TabGroup2);
            app.GraphixTab_2.Title = 'Graphix';
            app.OFFLabel_2 = uilabel(app.GraphixTab_2);
            app.OFFLabel_2.Text = 'OFF';
            app.OFFLabel_3 = uilabel(app.GraphixTab_2);
            app.OFFLabel_3.Text = 'OFF';
            app.CH2Label = uilabel(app.GraphixTab_2);
            app.CH2Label.Text = 'CH2:';
            app.CH3Label = uilabel(app.GraphixTab_2);
            app.CH3Label.Text = 'CH3:';
            app.Label = uilabel(app.GraphixTab_2);
            app.Label.Text = '';
            app.TC08degCTab = uitab(app.TabGroup2);
            app.TC08degCTab.Title = 'TC-08 (deg C)';
            app.UITable = uitable(app.TC08degCTab);
            app.UITable.ColumnName = {'T1'; 'T2'; 'T3'; 'T4'; 'T5'; 'T6'; 'T7'; 'T8'; 'T9'};
            app.UITable.RowName = {''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = logPressure

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end